classdef sin_cos < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure   matlab.ui.Figure
        UIAxes     matlab.ui.control.UIAxes
        SinButton  matlab.ui.control.Button
        CosButton  matlab.ui.control.Button
        AllButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        x_range =  -2*pi:pi/50:2*pi; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SinButton
        function SinButtonPushed(app, event)
           plot(app.UIAxes,app.x_range, sin(app.x_range));
        end

        % Button pushed function: CosButton
        function CosButtonPushed(app, event)

            plot(app.UIAxes,app.x_range, cos(app.x_range));
        end

        % Button pushed function: AllButton
        function AllButtonPushed(app, event)

            plot(app.UIAxes, app.x_range, sin(app.x_range), app.x_range, cos(app.x_range));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Plot')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XLim = [-6.28318530717959 6.28318530717959];
            app.SinButton = uibutton(app.UIFigure, 'push');
            app.SinButton.ButtonPushedFcn = createCallbackFcn(app, @SinButtonPushed, true);
            app.SinButton.Text = 'Sin';
            app.CosButton = uibutton(app.UIFigure, 'push');
            app.CosButton.ButtonPushedFcn = createCallbackFcn(app, @CosButtonPushed, true);
            app.CosButton.Text = 'Cos';
            app.AllButton = uibutton(app.UIFigure, 'push');
            app.AllButton.ButtonPushedFcn = createCallbackFcn(app, @AllButtonPushed, true);
            app.AllButton.Text = 'All';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sin_cos

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end